classdef Rummy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        DiscardButton           matlab.ui.control.Button
        CurrentPhaseLabel       matlab.ui.control.Label
        DrawfromDiscardButton   matlab.ui.control.Button
        DrawfromDrawPileButton  matlab.ui.control.Button
        DiscardPileLabel        matlab.ui.control.Label
        TopCardLabel            matlab.ui.control.Label
        DrawPileLabel           matlab.ui.control.Label
        YourHandButtonGroup     matlab.ui.container.ButtonGroup
        Card8Button             matlab.ui.control.RadioButton
        Card7Button             matlab.ui.control.RadioButton
        Card6Button             matlab.ui.control.RadioButton
        Card5Button             matlab.ui.control.RadioButton
        Card4Button             matlab.ui.control.RadioButton
        Card3Button             matlab.ui.control.RadioButton
        Card2Button             matlab.ui.control.RadioButton
        Card1Button             matlab.ui.control.RadioButton
        TurnLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        player1Hand;
        player2Hand;
        drawPile;
        discardPile;

        currentPlayerNum = 1;

        currentTurnPhase = "draw"


    end

    properties (Access = private, Dependent)
        currentPlayerHand
        selectedCardIndex
    end

    methods (Access = private)

        function updateHandDisplay(app)
            cardsList = app.currentPlayerHand.OutputCards;
            app.Card1Button.Text = cardsList(1);
            app.Card2Button.Text = cardsList(2);
            app.Card3Button.Text = cardsList(3);
            app.Card4Button.Text = cardsList(4);
            app.Card5Button.Text = cardsList(5);
            app.Card6Button.Text = cardsList(6);
            app.Card7Button.Text = cardsList(7);
            if length(cardsList) == 8
                app.Card8Button.Text = cardsList(8);
            else
                app.Card8Button.Text = "Empty";
            end
        end

        function nextPhase(app)
            currentPhase = app.currentTurnPhase;
            switch currentPhase
                case "draw"
                    % Switch to Discard Phase
                    app.currentTurnPhase = "discard";
                    app.CurrentPhaseLabel.Text = "Discard Phase";
                    app.DrawfromDrawPileButton.Visible = "off";
                    app.DrawfromDiscardButton.Visible = "off";
                    app.DiscardButton.Visible = "on";
                case "discard"
                    % Switch to Draw Phase
                    app.currentTurnPhase = "draw";
                    app.CurrentPhaseLabel.Text = "Draw Phase";
                    app.DrawfromDrawPileButton.Visible = "on";
                    app.DrawfromDiscardButton.Visible = "on";
                    app.DiscardButton.Visible = "off";
                    
                    % It must be time to switch turns
                    app.nextTurn
            end
        end

        function nextTurn(app)
            prevPlayerNum = app.currentPlayerNum;
            switch prevPlayerNum
                case 1
                    app.currentPlayerNum = 2;
                case 2
                    app.currentPlayerNum = 1;
            end

            app.TurnLabel.Text = "Player " + app.currentPlayerNum;
            app.updateHandDisplay;
        end


        function [] = updateTopCard(app)
            app.TopCardLabel.Text = app.discardPile.topCard;
        end
        
    end

    methods

        function currentPlayerHand = get.currentPlayerHand(app)
            switch app.currentPlayerNum
                case 1
                    currentPlayerHand = app.player1Hand;
                case 2
                    currentPlayerHand = app.player2Hand;
            end
        end

        % function set.currentTurnPhase(app, newPhase)
        %     if newPhase == "draw" || newPhase == "discard"
        %         app.currentTurnPhase = newPhase;
        %         app.updatePhase;
        %     else
        %         errordlg("Invalid turn phase.")
        %     end
        % 
        % end


        function selectedCardIndex = get.selectedCardIndex(app)
            logicalArray = [app.Card1Button.Value,...
                app.Card2Button.Value,...
                app.Card3Button.Value,...
                app.Card4Button.Value,...
                app.Card5Button.Value,...
                app.Card6Button.Value,...
                app.Card7Button.Value,...
                app.Card8Button.Value];
            nums = 1:8;
            selectedCardIndex = nums(logicalArray);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.drawPile = DrawPile(true);
            app.discardPile = CardArray([]);
            app.player1Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.player2Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.drawPile.Shuffle()
            app.drawPile.DealHands([app.player1Hand, app.player2Hand], app.discardPile);
            app.updateHandDisplay;
                    % Switch to Draw Phase
                    app.currentTurnPhase = "draw";
                    app.CurrentPhaseLabel.Text = "Draw Phase";
                    app.DrawfromDrawPileButton.Visible = "on";
                    app.DrawfromDiscardButton.Visible = "on";
                    app.DiscardButton.Visible = "off";
            app.updateTopCard;
            app.TurnLabel.Text = "Player 1";
        end

        % Button pushed function: DrawfromDrawPileButton
        function DrawfromDrawPileButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(false);
                    app.updateHandDisplay;
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to draw a card right now.")
            end
        end

        % Button pushed function: DrawfromDiscardButton
        function DrawfromDiscardButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(true);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to draw a card right now.")
            end
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            switch app.currentTurnPhase
                case "discard"
                    app.currentPlayerHand.discard(app.selectedCardIndex);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to discard a card right now.")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TurnLabel = uilabel(app.UIFigure);
            app.TurnLabel.Text = 'Turn';
            app.YourHandButtonGroup = uibuttongroup(app.UIFigure);
            app.YourHandButtonGroup.Title = 'Your Hand';
            app.Card1Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card1Button.Text = 'Card 1';
            app.Card1Button.Value = true;
            app.Card2Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card2Button.Text = 'Card 2';
            app.Card3Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card3Button.Text = 'Card 3';
            app.Card4Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card4Button.Text = 'Card 4';
            app.Card5Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card5Button.Text = 'Card 5';
            app.Card6Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card6Button.Text = 'Card 6';
            app.Card7Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card7Button.Text = 'Card 7';
            app.Card8Button = uiradiobutton(app.YourHandButtonGroup);
            app.Card8Button.Text = 'Card 8';
            app.DrawPileLabel = uilabel(app.UIFigure);
            app.DrawPileLabel.Text = 'Draw Pile';
            app.TopCardLabel = uilabel(app.UIFigure);
            app.TopCardLabel.Text = 'Top Card';
            app.DiscardPileLabel = uilabel(app.UIFigure);
            app.DiscardPileLabel.Text = 'Discard Pile';
            app.DrawfromDrawPileButton = uibutton(app.UIFigure, 'push');
            app.DrawfromDrawPileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawfromDrawPileButtonPushed, true);
            app.DrawfromDrawPileButton.Text = {'Draw  '; 'from Draw Pile'};
            app.DrawfromDiscardButton = uibutton(app.UIFigure, 'push');
            app.DrawfromDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DrawfromDiscardButtonPushed, true);
            app.DrawfromDiscardButton.Text = {'Draw'; 'from Discard'};
            app.CurrentPhaseLabel = uilabel(app.UIFigure);
            app.CurrentPhaseLabel.Text = 'Current Phase';
            app.DiscardButton = uibutton(app.UIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Visible = 'off';
            app.DiscardButton.Text = 'Discard';
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rummy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end